classdef Snell_Law_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        ContinuarButton        matlab.ui.control.Button
        N_medios_Label         matlab.ui.control.Label
        AnguloLabel            matlab.ui.control.Label
        Angulo                 matlab.ui.control.NumericEditField
        EspesoresLabel         matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        Espesores              matlab.ui.control.EditField
        N_medios               matlab.ui.control.NumericEditField
        Indices                matlab.ui.control.EditField
        DireccinDropDown       matlab.ui.control.DropDown
        DireccinDropDownLabel  matlab.ui.control.Label
        HeaderLabel            matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        Angulos2_Label         matlab.ui.control.Label
        Header2                matlab.ui.control.Label
        Angulos_TextArea       matlab.ui.control.TextArea
        OutP                   matlab.ui.control.UIAxes
        OpcionesMenu           matlab.ui.container.Menu
        TemaMenu               matlab.ui.container.Menu
        OscuroMenu             matlab.ui.container.Menu
        ClaroMenu              matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OscuroMenu
        function OscuroMenuSelected(app, event)
            
            app.LeftPanel.BackgroundColor = [0 0 0];
            app.RightPanel.BackgroundColor = [0 0 0];
            app.HeaderLabel.FontColor = [1 1 1];
            app.DireccinDropDownLabel.FontColor = [1 1 1];
            app.Label.FontColor = [1 1 1];
            app.EspesoresLabel.FontColor = [1 1 1];
            app.AnguloLabel.FontColor = [1 1 1];
            app.N_medios_Label.FontColor = [1 1 1];
            app.OutP.XColor = [1 1 1];
            app.OutP.YColor = [1 1 1];
            app.Header2.FontColor = [1 1 1];
            app.Angulos2_Label.FontColor = [1 1 1];
            app.OutP.Color = [0 0 0];
            app.OutP.GridColor = [1 1 1];

        end

        % Menu selected function: ClaroMenu
        function ClaroMenuSelected(app, event)
            
            app.LeftPanel.BackgroundColor = [1 1 1];
            app.RightPanel.BackgroundColor = [1 1 1];
            app.HeaderLabel.FontColor = [0 0 0];
            app.DireccinDropDownLabel.FontColor = [0 0 0];
            app.Label.FontColor = [0 0 0];
            app.EspesoresLabel.FontColor = [0 0 0];
            app.AnguloLabel.FontColor = [0 0 0];
            app.N_medios_Label.FontColor = [0 0 0];
            app.OutP.XColor = [0 0 0];
            app.OutP.YColor = [0 0 0];
            app.Header2.FontColor = [0 0 0];
            app.Angulos2_Label.FontColor = [0 0 0];
            app.OutP.Color = [1 1 1];
            app.OutP.GridColor = [0 0 0];

        end

        % Value changed function: N_medios
        function N_mediosValueChanged(app, event)
            
            app.OutP.XLabel.String = "NNNN";
            if app.N_medios.Value == 1

                app.Label.Text = "Indice de refracción";
                app.EspesoresLabel.Text = "Espesor";
    
            else

                app.Label.Text = "Indices de refracción";
                app.EspesoresLabel.Text = "Espesores";

            end

            app.Indices.Visible = "On";
            app.Espesores.Visible = "On";
            app.Label.Visible = "On";
            app.EspesoresLabel.Visible = "On";
            app.Angulo.Visible = "On";
            app.AnguloLabel.Visible = "On";
            
        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
            
            hold(app.OutP, "Off")
            app.Header2.Enable = "On";
            app.Angulos2_Label.Enable = "On";
            app.Angulos_TextArea.Enable = "On";
            Cantidad_de_medios = app.N_medios.Value;
            Direccion = app.DireccinDropDown.Value;
            
            Valores_Indices = app.Indices.Value;
            Valores_Indices = convertStringsToChars(Valores_Indices);
            comas_Indices = find(Valores_Indices == ',');
            pos = 1;

            for i = 1:length(comas_Indices) + 1

                if i == length(comas_Indices) + 1

                    n_Indices(i) = convertCharsToStrings(Valores_Indices(pos:length(Valores_Indices)));

                else

                    n_Indices(i) = convertCharsToStrings(Valores_Indices(pos:comas_Indices(i) - 1));
                    pos = comas_Indices(i) + 1;

                end

            end


            Valores_Espesores = app.Espesores.Value;
            Valores_Espesores = convertStringsToChars(Valores_Espesores);
            comas_Espesores = find(Valores_Espesores == ',');
            pos = 1;

            for i = 1:length(comas_Espesores) + 1

                if i == length(comas_Espesores) + 1

                    n_Espesores(i) = convertCharsToStrings(Valores_Espesores(pos:length(Valores_Espesores)));

                else

                    n_Espesores(i) = convertCharsToStrings(Valores_Espesores(pos:comas_Espesores(i) - 1));
                    pos = comas_Espesores(i) + 1;

                end

            end

            n_Espesores = str2double(n_Espesores);
            n_Indices = str2double(n_Indices);
            Valor_Angulo = app.Angulo.Value;
            app.RightPanel.Enable = "On";

            if Cantidad_de_medios == length(n_Indices) && Cantidad_de_medios == length(n_Espesores)
                
                Valor_Angulo = deg2rad(Valor_Angulo);
                angulos = [];
                angulos(1) = Valor_Angulo;
                Ms = [];
                Ms(1) = tan(Valor_Angulo);

                for i = 1:length(n_Indices)

                    if i < length(n_Indices)

                        angulo2 = asin((n_Indices(i)/n_Indices(i+1))*sin(Valor_Angulo));

                        if imag(angulo2) == 0

                            Valor_Angulo = angulo2;
                            angulos(i + 1) = angulo2;
                            Ms(i + 1) = tan(Valor_Angulo);

                        else

                            angulos(i + 1) = Valor_Angulo;
                            Ms(i + 1) = tan(Valor_Angulo);
                            i = length(n_Indices);

                        end

                    end

                end

                if Direccion == "Abajo"

                    Ms = - Ms;

                end

                y = [];
                y(1) = 0;
                x = zeros(1,length(n_Espesores)+1);
                suma = 0;

                for i = 1:length(Ms)

                    y(i + 1) = Ms(i) * sum(n_Espesores(1:i)) + suma;
                    x(i + 1) = sum(n_Espesores(1:i));
                    suma = suma + y(i + 1);

                end

                plot(app.OutP, x, y, 'r', 'LineWidth', 3)
                hold(app.OutP, "On")
                xline(app.OutP, x, 'b', 'LineWidth', 1)
                Out_Angulos = rad2deg(angulos);

                for i=1:length(Out_Angulos)

                    n(i)=strcat("n",num2str(i)," → ",num2str(Out_Angulos(i)));

                end

                app.Angulos_TextArea.Value = n;
                app.OutP.XLim = [0 x(length(x))];

            else

            end
            
        end

        % Value changed function: Angulo
        function AnguloValueChanged(app, event)
            
            if app.Indices.Value ~= "" && app.Espesores.Value ~= "" && app.Angulo.Value > 0

                app.ContinuarButton.Enable = "On";

            else
                
                app.ContinuarButton.Enable = "Off";

            end

        end

        % Value changed function: Espesores
        function EspesoresValueChanged(app, event)
           
            if app.Indices.Value ~= "" && app.Espesores.Value ~= "" && app.Angulo.Value > 0

                app.ContinuarButton.Enable = "On";

            else
                
                app.ContinuarButton.Enable = "Off";

            end
            
        end

        % Value changed function: Indices
        function IndicesValueChanged(app, event)
            
            if app.Indices.Value ~= "" && app.Espesores.Value ~= "" && app.Angulo.Value > 0

                app.ContinuarButton.Enable = "On";

            else
                
                app.ContinuarButton.Enable = "Off";

            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {413, 413};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {383, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.HeaderLabel = uilabel(app.LeftPanel);
            app.HeaderLabel.Text = 'LEY DE SNELL';
            app.DireccinDropDownLabel = uilabel(app.LeftPanel);
            app.DireccinDropDownLabel.Text = 'Dirección';
            app.DireccinDropDown = uidropdown(app.LeftPanel);
            app.DireccinDropDown.Items = {'Arriba', 'Abajo'};
            app.DireccinDropDown.Value = 'Arriba';
            app.Indices = uieditfield(app.LeftPanel, 'text');
            app.Indices.ValueChangedFcn = createCallbackFcn(app, @IndicesValueChanged, true);
            app.Indices.Visible = 'off';
            app.Indices.Tooltip = {'Ingrese los índices separados únicamente con comas, valores decimales con punto, de esta forma:'; '1.5,1.8,1.7,1.9'};
            app.N_medios = uieditfield(app.LeftPanel, 'numeric');
            app.N_medios.Limits = [1 Inf];
            app.N_medios.ValueChangedFcn = createCallbackFcn(app, @N_mediosValueChanged, true);
            app.N_medios.Value = 1;
            app.Espesores = uieditfield(app.LeftPanel, 'text');
            app.Espesores.ValueChangedFcn = createCallbackFcn(app, @EspesoresValueChanged, true);
            app.Espesores.Visible = 'off';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Visible = 'off';
            app.Label.Text = 'Indices de refracción';
            app.EspesoresLabel = uilabel(app.LeftPanel);
            app.EspesoresLabel.Visible = 'off';
            app.EspesoresLabel.Text = 'Espesores';
            app.Angulo = uieditfield(app.LeftPanel, 'numeric');
            app.Angulo.Limits = [0 Inf];
            app.Angulo.ValueChangedFcn = createCallbackFcn(app, @AnguloValueChanged, true);
            app.Angulo.Visible = 'off';
            app.AnguloLabel = uilabel(app.LeftPanel);
            app.AnguloLabel.Visible = 'off';
            app.AnguloLabel.Text = 'Ángulo de incidencia';
            app.N_medios_Label = uilabel(app.LeftPanel);
            app.N_medios_Label.Text = 'Cantidad de medios';
            app.ContinuarButton = uibutton(app.LeftPanel, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Enable = 'off';
            app.ContinuarButton.Text = 'Continuar';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Enable = 'off';
            app.OutP = uiaxes(app.RightPanel);
            zlabel(app.OutP, 'Z')
            app.Angulos_TextArea = uitextarea(app.RightPanel);
            app.Angulos_TextArea.Editable = 'off';
            app.Angulos_TextArea.Enable = 'off';
            app.Header2 = uilabel(app.RightPanel);
            app.Header2.Enable = 'off';
            app.Header2.Text = 'Trayectoria del rayo de luz';
            app.Angulos2_Label = uilabel(app.RightPanel);
            app.Angulos2_Label.Enable = 'off';
            app.Angulos2_Label.Text = 'Ángulos de refracción para cada medio';
            app.OpcionesMenu = uimenu(app.UIFigure);
            app.OpcionesMenu.Text = 'Opciones';
            app.TemaMenu = uimenu(app.OpcionesMenu);
            app.TemaMenu.Text = 'Tema';
            app.OscuroMenu = uimenu(app.TemaMenu);
            app.OscuroMenu.MenuSelectedFcn = createCallbackFcn(app, @OscuroMenuSelected, true);
            app.OscuroMenu.Text = 'Oscuro';
            app.ClaroMenu = uimenu(app.TemaMenu);
            app.ClaroMenu.MenuSelectedFcn = createCallbackFcn(app, @ClaroMenuSelected, true);
            app.ClaroMenu.Text = 'Claro';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Snell_Law_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end